classdef StudentManagement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ButtonGroup                matlab.ui.container.ButtonGroup
        WomanButton                matlab.ui.control.RadioButton
        ManButton                  matlab.ui.control.RadioButton
        SearchButton               matlab.ui.control.Button
        SearchEditField            matlab.ui.control.EditField
        SearchEditFieldLabel       matlab.ui.control.Label
        ManCheckBox                matlab.ui.control.CheckBox
        WomanCheckBox              matlab.ui.control.CheckBox
        MailEditField              matlab.ui.control.EditField
        MailEditFieldLabel         matlab.ui.control.Label
        DepartmentEditField        matlab.ui.control.EditField
        DepartmentLabel            matlab.ui.control.Label
        DeleteStudentButton        matlab.ui.control.Button
        UpdateInfoButton           matlab.ui.control.Button
        AddStudentButton           matlab.ui.control.Button
        StudentGPAEditField        matlab.ui.control.NumericEditField
        StudentGPAEditFieldLabel   matlab.ui.control.Label
        StudentNameEditField       matlab.ui.control.EditField
        StudentNameEditFieldLabel  matlab.ui.control.Label
        StudentNoEditField         matlab.ui.control.NumericEditField
        StudentNoEditFieldLabel    matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        GenderDistAxes             matlab.ui.control.UIAxes
        GenderGPAAxes              matlab.ui.control.UIAxes
        DeptGPAAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property
        FullData
    end
    
    methods (Access = private)
        
        function results = updateDashboard(app)

            if isempty(app.FullData) || height(app.FullData) == 0
                cla(app.DeptGPAAxes); cla(app.GenderGPAAxes); cla(app.GenderDistAxes);
                return;
            end
        
            deptStats = groupsummary(app.FullData, 'Department', 'mean', 'GPA');
            bar(app.DeptGPAAxes, deptStats.Department, deptStats.mean_GPA);
            title(app.DeptGPAAxes, 'Average GPA by Department');
        
            genderGPA = groupsummary(app.FullData, 'Gender', 'mean', 'GPA');
            bar(app.GenderGPAAxes, genderGPA.Gender, genderGPA.mean_GPA);
            title(app.GenderGPAAxes, 'Average GPA by Gender');
        
            genderCounts = groupsummary(app.FullData, 'Gender');
            pie(app.GenderDistAxes, genderCounts.GroupCount, genderCounts.Gender);
            title(app.GenderDistAxes, 'Gender Distribution');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           if isfile('Student.csv')
                app.FullData = readtable('Student.csv');
           else
                app.FullData = table(zeros(0,1), {}, {}, {}, zeros(0,1), {}, ...
                    'VariableNames', {'No', 'Name', 'Gender', 'Department', 'GPA', 'Mail'});
            end
            app.UITable.Data = table2cell(app.FullData);
            app.updateDashboard();
        end

        % Button pushed function: AddStudentButton
        function AddStudentButtonPushed(app, event)
           gender = 'Not Specified';
            if app.ManButton.Value, gender = 'Man'; 
            elseif app.WomanButton.Value, gender = 'Woman'; end
        
            newrow = table(app.StudentNoEditField.Value, {app.StudentNameEditField.Value}, ...
                {gender}, {app.DepartmentEditField.Value}, app.StudentGPAEditField.Value, ...
                {app.MailEditField.Value}, 'VariableNames', app.FullData.Properties.VariableNames);
        
            app.FullData = [app.FullData; newrow];
            writetable(app.FullData, 'Student.csv');
            app.UITable.Data = table2cell(app.FullData);
            
            app.StudentNoEditField.Value = 0;
            app.StudentNameEditField.Value = '';
            app.StudentGPAEditField.Value = 0;
            app.DepartmentEditField.Value = '';
            app.MailEditField.Value = '';
            app.ManCheckBox.Value = false;
            app.WomanCheckBox.Value = false;
        end

        % Button pushed function: DeleteStudentButton
        function DeleteStudentButtonPushed(app, event)
      
            selectedIndices = app.UITable.Selection; 
            if isempty(selectedIndices)
                uialert(app.UIFigure, 'Please select a student to delete!', 'Error');
                return;
            end
            
            rowInUI = selectedIndices(1);
            
            studentNoToDelete = app.UITable.Data{rowInUI, 1};
        
            logicalIndex = (app.FullData.No == studentNoToDelete);
            
            if any(logicalIndex)
        
                app.FullData(logicalIndex, :) = []; 
                
                writetable(app.FullData, 'Student.csv');
                app.UITable.Data = table2cell(app.FullData);
                
                app.updateDashboard(); 
                
                uiconfirm(app.UIFigure, 'The student has been successfully deleted.', 'Succes');
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
           indices = event.Indices;
            if isempty(indices)
                return;
            end
            rowNo = indices(1);
            
            app.StudentNoEditField.Value   = app.UITable.Data{rowNo, 1};
            app.StudentNameEditField.Value = app.UITable.Data{rowNo, 2};
            
            cinsiyet = app.UITable.Data{rowNo, 3};
            app.ManCheckBox.Value = strcmp(cinsiyet, 'Man');
            app.WomanCheckBox.Value = strcmp(cinsiyet, 'Woman');
            
            app.DepartmentEditField.Value  = app.UITable.Data{rowNo, 4};
            app.StudentGPAEditField.Value   = app.UITable.Data{rowNo, 5};
            app.MailEditField.Value        = app.UITable.Data{rowNo, 6};
        end

        % Button pushed function: UpdateInfoButton
        function UpdateInfoButtonPushed(app, event)
           selectedRow = app.UITable.Selection;
            if isempty(selectedRow)
                uialert(app.UIFigure, 'Select a row to update!', 'Warning');
                return;
            end
            
            target = selectedRow(1);
            
            gender = 'Not Specified';
            if app.ManButton.Value, gender = 'Man'; 
            elseif app.WomanButton.Value, gender = 'Woman'; end
        
            app.FullData.No(target) = app.StudentNoEditField.Value;
            app.FullData.Name{target} = app.StudentNameEditField.Value;
            app.FullData.Gender{target} = gender;
            app.FullData.Department{target} = app.DepartmentEditField.Value;
            app.FullData.GPA(target) = app.StudentGPAEditField.Value;
            app.FullData.Mail{target} = app.MailEditField.Value;
            
            writetable(app.FullData, 'Student.csv');
            app.UITable.Data = table2cell(app.FullData);
            
            uiconfirm(app.UIFigure, 'Updated successfully.', 'Success', 'Options', {'OK'});

            app.StudentNoEditField.Value = 0;
            app.StudentNameEditField.Value = '';
            app.StudentGPAEditField.Value = 0;
            app.DepartmentEditField.Value = '';
            app.MailEditField.Value = '';
            app.ManCheckBox.Value = false;
            app.WomanCheckBox.Value = false;
        end

        % Value changing function: SearchEditField
        function SearchEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
           searchedName = app.SearchEditField.Value; 
    
            if isempty(app.FullData) || ~istable(app.FullData)
                uialert(app.UIFigure, 'There are no students registered in the system!', 'Error');
                return;
            end
            
            if isempty(searchedName)
                app.UITable.Data = table2cell(app.FullData);
                return;
            end
            
            matched = contains(app.FullData.Name, searchedName, 'IgnoreCase', true);
            filtreliVeri = app.FullData(matched, :);
            app.UITable.Data = table2cell(filtreliVeri);
            
            if isempty(filtreliVeri)
                uialert(app.UIFigure, [searchedName ' Not Found.'], 'Search Result');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DeptGPAAxes = uiaxes(app.UIFigure);
            title(app.DeptGPAAxes, 'Title')
            xlabel(app.DeptGPAAxes, 'X')
            ylabel(app.DeptGPAAxes, 'Y')
            zlabel(app.DeptGPAAxes, 'Z')
            app.GenderGPAAxes = uiaxes(app.UIFigure);
            title(app.GenderGPAAxes, 'Title')
            xlabel(app.GenderGPAAxes, 'X')
            ylabel(app.GenderGPAAxes, 'Y')
            zlabel(app.GenderGPAAxes, 'Z')
            app.GenderDistAxes = uiaxes(app.UIFigure);
            title(app.GenderDistAxes, 'Title')
            xlabel(app.GenderDistAxes, 'X')
            ylabel(app.GenderDistAxes, 'Y')
            zlabel(app.GenderDistAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Student No'; 'Name Surname'; 'Gender'; 'Department'; 'GPA'; 'Mail'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.StudentNoEditFieldLabel = uilabel(app.UIFigure);
            app.StudentNoEditFieldLabel.Text = 'Student No:';
            app.StudentNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.StudentNameEditFieldLabel = uilabel(app.UIFigure);
            app.StudentNameEditFieldLabel.Text = 'Student Name:';
            app.StudentNameEditField = uieditfield(app.UIFigure, 'text');
            app.StudentGPAEditFieldLabel = uilabel(app.UIFigure);
            app.StudentGPAEditFieldLabel.Text = 'StudentGPA:';
            app.StudentGPAEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddStudentButton = uibutton(app.UIFigure, 'push');
            app.AddStudentButton.ButtonPushedFcn = createCallbackFcn(app, @AddStudentButtonPushed, true);
            app.AddStudentButton.Text = 'Add Student';
            app.UpdateInfoButton = uibutton(app.UIFigure, 'push');
            app.UpdateInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateInfoButtonPushed, true);
            app.UpdateInfoButton.Text = 'Update Info';
            app.DeleteStudentButton = uibutton(app.UIFigure, 'push');
            app.DeleteStudentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStudentButtonPushed, true);
            app.DeleteStudentButton.Text = 'Delete Student';
            app.DepartmentLabel = uilabel(app.UIFigure);
            app.DepartmentLabel.Text = 'Department:';
            app.DepartmentEditField = uieditfield(app.UIFigure, 'text');
            app.MailEditFieldLabel = uilabel(app.UIFigure);
            app.MailEditFieldLabel.Text = 'Mail:';
            app.MailEditField = uieditfield(app.UIFigure, 'text');
            app.WomanCheckBox = uicheckbox(app.UIFigure);
            app.WomanCheckBox.Text = 'Woman';
            app.ManCheckBox = uicheckbox(app.UIFigure);
            app.ManCheckBox.Text = 'Man';
            app.SearchEditFieldLabel = uilabel(app.UIFigure);
            app.SearchEditFieldLabel.Text = 'Search:';
            app.SearchEditField = uieditfield(app.UIFigure, 'text');
            app.SearchEditField.ValueChangingFcn = createCallbackFcn(app, @SearchEditFieldValueChanging, true);
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ManButton = uiradiobutton(app.ButtonGroup);
            app.ManButton.Text = 'Man';
            app.ManButton.Value = true;
            app.WomanButton = uiradiobutton(app.ButtonGroup);
            app.WomanButton.Text = 'Woman';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StudentManagement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end